classdef Minesweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UsernameEditFieldLabel        matlab.ui.control.Label
        UsernameEditField             matlab.ui.control.EditField
        PasswordEditFieldLabel        matlab.ui.control.Label
        PasswordEditField             matlab.ui.control.EditField
        StartGameButton               matlab.ui.control.Button
        GameStatusEditFieldLabel      matlab.ui.control.Label
        GameStatusEditField           matlab.ui.control.EditField
        NumberOfBombsSpinnerLabel     matlab.ui.control.Label
        NumberOfBombsSpinner          matlab.ui.control.Spinner
        NumberofFlagsSpinnerLabel     matlab.ui.control.Label
        NumberofFlagsSpinner          matlab.ui.control.Spinner
        FlagsRemainingEditFieldLabel  matlab.ui.control.Label
        FlagsRemainingEditField       matlab.ui.control.NumericEditField
        BombsRemainingEditFieldLabel  matlab.ui.control.Label
        BombsRemainingEditField       matlab.ui.control.NumericEditField
        UpButton                      matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        PlaceFlagButton               matlab.ui.control.Button
        MakeSelectionButton           matlab.ui.control.Button
        BombFlaggedLampLabel          matlab.ui.control.Label
        BombFlaggedLamp               matlab.ui.control.Lamp
        NotaBombLampLabel             matlab.ui.control.Label
        NotaBombLamp                  matlab.ui.control.Lamp
        QuitGameButton                matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        EditField2                    matlab.ui.control.EditField
        EditField3                    matlab.ui.control.EditField
        EditField4                    matlab.ui.control.EditField
        EditField5                    matlab.ui.control.EditField
        EditField6                    matlab.ui.control.EditField
        EditField7                    matlab.ui.control.EditField
        EditField8                    matlab.ui.control.EditField
        EditField9                    matlab.ui.control.EditField
        EditField10                   matlab.ui.control.EditField
        EditField11                   matlab.ui.control.EditField
        EditField12                   matlab.ui.control.EditField
        MINESWEEPERLabel              matlab.ui.control.Label
        BoardLabel                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        a = arduino();
        pins = [ 
                "D13" "D12" "D11"; 
                "D10" "D9" "D8"; 
                "D7" "D6" "D5"; 
                "D4" "D3" "D2"
                ];

         board = [
            0,0, 0;
            0, 0, 0;
            0, 0, 0;
            0, 0 ,0
            ];
         
         memory = zeros(4,3);
         makeSelectionMemory = zeros(4,3);
         
        on = ones(4,3); % This writes a matrix filled with 1's according to size of pins
        off = zeros(4,3);
            
        bombs = zeros(4,3);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            % NEED help with user credentials 
            %  - Currently commented what i think works for credentials
            % Need help with user starting game 
            % QUESTION FOR TA: Are we allowed to have functions in the
            % folder so we can refer to or only 1 file? <= NOT IMPORTANT RN
            
            %NEED: Create a way to have a cursor that we can reference and
            %able to modify
            
            % Check below for more details
        
           
            % NOTE: variables are local to the function?
          
            
            [ rows, cols ] = size(app.pins);


            Checking = 0;
            % If the user inputs the correct information via the fields
            % then the checking turns to 1 
            % when we actually start the game , check if the 'checking' is 2
          username = 'abc123';
          password = 'password';
          % checking if user has valid credentials
          % '' charachter array
          %"" string array comparsion
          if strcmp(app.UsernameEditField.Value,username) & strcmp(app.PasswordEditField.Value,password)
            if(app.NumberOfBombsSpinner.Value<=app.NumberofFlagsSpinner.Value)
            Checking = 1;
            end
         end
          
          
          if (Checking == 1) % GAME STARTS FROM HERE
          
          % this will be the code for the game
          %Game Buttons
          %Start Game button
          
          app.GameStatusEditField.Value = 'Game Started!';
          
          app.board(1,1)=1;  % Starting location 
          UpdateBoard(app.a,app.pins,app.board)
          
          
          %This is Game status code
          % -----------------------------
          app.FlagsRemainingEditField.Value = app.NumberofFlagsSpinner.Value;  % Sets the flags remaining = the set flags
          app.BombsRemainingEditField.Value = app.NumberOfBombsSpinner.Value;
          
          % -----------------------------
          
          % If user places a flag thats not a bomb then the NOT A BOMB lamp
          % lights up
          % 
          % PLACING BOMBS on unique board
          %------------------
          value = randperm(12,app.NumberOfBombsSpinner.Value) % Creates a unique values(determined by bomb spiner value) b/w 1-12 
          app.bombs(value) = 1; % Sets them on the bomb board =1
      
          %------------------
          
            % Dim the lamps
          app.BombFlaggedLamp.Enable ='off';
          app.NotaBombLamp.Enable = 'off';
           else
                app.GameStatusEditField.Value = 'Please enter valid credentials';
           end
          %
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % when button is pushed it -1 from the whatever row the cursor
            % LED is currently at
            
            % NOTE: need to figure out how to create a unviersal cursor LED
            % that can be refrenced through out through whole app. 
            %Example: We set the curors = writeDigitalPin(a,D13,1) but were
            %able to modify its position and know where its at and if the
            %lights on
            %Need to be able to reference this for all 4 buttons
            % Maybe need to use for loop that just checks spot somehow?
            [row,col]= find(app.board==1);
            if row>1
                app.board(row,col)=0;
                app.board(row-1,col)=1;
                UpdateBoard(app.a,app.pins,app.board)
                
            end


        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
           [row,col]= find(app.board==1);
            if row <4
                app.board(row,col)=0;
                app.board(row+1,col)=1; % Alters the board
                UpdateBoard(app.a,app.pins,app.board) % Updates the LEDS
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            %find  where the board is 1 and then change to 0 and then change 
             [row,col]= find(app.board==1);
            if col<3
                app.board(row,col)=0;
                app.board(row,col+1)=1; % Alters the board
                UpdateBoard(app.a,app.pins,app.board) % Updates the LEDS
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            [row,col]= find(app.board==1);
            if col>1 
                app.board(row,col)=0;
                app.board(row,col-1)=1; % Alters the board
                UpdateBoard(app.a,app.pins,app.board) % Updates the LEDS
            end
        end

        % Button pushed function: QuitGameButton
        function QuitGameButtonPushed(app, event)
       [row,col]= find(app.board==1);% Gives us location of cursor
            UpdateBoard(app.a,app.pins,app.off) % Not sure if need app as argument 

            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';
            app.GameStatusEditField.Value = 'Not Started';
            app.BombsRemainingEditField.Value = 0;
            app.FlagsRemainingEditField.Value = 0;
            app.NumberOfBombsSpinner.Value = 1;
            app.NumberofFlagsSpinner.Value = 1;

            %need  to reset the 4x3 board


          ef = [

          app.EditField,app.EditField2,app.EditField3;
          app.EditField4,app.EditField5,app.EditField6;
          app.EditField7,app.EditField8,app.EditField9,
          app.EditField10,app.EditField11,app.EditField12

          ]

          %[row, col] = size(app.board);
        %  for i=1:row 
          %   for j=1:col
             %    ef(row,col) = '';
          %   end
         % end

            app.EditField.Value = '';
            app.EditField2.Value = '';
            app.EditField3.Value = '';
            app.EditField4.Value = '';
            app.EditField5.Value = '';
            app.EditField6.Value = '';
            app.EditField7.Value = '';
            app.EditField8.Value = '';
            app.EditField9.Value = '';
            app.EditField10.Value = '';
            app.EditField11.Value = '';
            app.EditField12.Value = '';
            for i =1:4
                for j=1:3
                    ef(i,j).BackgroundColor = [1,1,1];
                end
            end


        end

        % Button pushed function: MakeSelectionButton
        function MakeSelectionButtonPushed(app, event)
            %Selction Button
            [row,col]= find(app.board==1);% Gives us location of cursor
            [bombRow,bombCol]= find(app.bombs == 1) % location of bombs
            
            % These two if statements are if we HIT a bomb or theres 0
            % flags and still bombs remaining
           
            if (app.bombs(row,col) == 1)                  % If bomb is selected ( losing screen) 
               %  row == bombRow)&(col==bombCol)   
               
                UpdateBoard(app.a,app.pins,app.off);
                %  % This will lights up all the LEDS before your curisor 
                app.GameStatusEditField.Value = 'You Hit A Bomb! You Lose';
                for i=1:row
                    for j=1:col
                        
                            
                            app.board(i,j)= 1;
                            UpdateBoard(app.a,app.pins,app.board)
                            pause(1);
                        
                    end
                end
%              
                
                %Put app.enable when they lose except in the quit button
            end
            
             if(app.FlagsRemainingEditField.Value==0 & app.BombsRemainingEditField.Value>0)
                 
                app.GameStatusEditField.Value = 'You have no flags remaining! You Lose';
                for i=1:row
                    for j=1:col
                        
                            
                            app.board(i,j)= 1;
                            UpdateBoard(app.a,app.pins,app.board)
                            pause(1);
                        
                    end
                end
                
%                   
             
             end 
            
            % If bomb is not selected
            % check if any bombs near by cursor location then places
            % corresponding number
            
            % Ask if need to check diagnols < --- Answer yes
            %  if row below , row above , col to the sides , 
          % Add +1 to numberOfBOms if theres a 
        %  [RBomb,CBomb] = size(app.bombs)
          
          %Approach: Assuming(2,2) ... Add the first row , add each side,
          %adds the bottom row
         
          numberOfBombs = 0;
          % Inital bomba adjacent code
%           %middle col 2-3 Row
%           if (col == 2)&(row<=3)&(row>=2) %and or OR row 2/3
%               for i=(row-1):(row+1)
%                   for j=(col-1):(col+1)
%                       numberOfBombs = numberOfBombs + app.bombs(i,j)
%                   end
%               end
%               numberOfBombs = numberOfBombs -app.bombs(row,col);
%           end
%           %1st Row middle 2 col
%           if(row==1)&(col==2)
%               for i=row:(row+1)
%                   for j=(col-1):(col+1)
%                       numberOfBombs = numberOfBombs + app.bombs(i,j)
%                   end
%               end
%               numberOfBombs = numberOfBombs -app.bombs(row,col);
%           end
%           %Last Row col 2 
%           if(row==4)&(col==2)
%               for i=row-1:row
%                   for j=(col-1):(col+1)
%                       numberOfBombs = numberOfBombs + app.bombs(i,j)
%                   end
%               end
%               numberOfBombs = numberOfBombs -app.bombs(row,col);
%           end
%           
%           
%           % Sides of the board
%           
%           %1st col 2nd and 3rd row
%           if (col==1) & (row<=3)&(row>=2)
%               for i=(row-1):(row+1)
%                   for j=col:(col+1)
%                       numberOfBombs = numberOfBombs + app.bombs(i,j)
%                   end
%               end
%               numberOfBombs = numberOfBombs -app.bombs(row,col);
%           end
%           
%           %last col 2nd and 3rd row
%           if (col==3)& (row<=3)&(row>=2)
%               for i=(row-1):(row+1)
%                   for j=(col-1):col
%                       numberOfBombs = numberOfBombs + app.bombs(i,j)
%                   end
%               end
%               numberOfBombs = numberOfBombs -app.bombs(row,col);
%           end
%           
%           
%           %----
%            % Corners of board NOTE: NEED TO CHECK CORNERS
%           if (row == 1) & (col==1)
%               numberOfBombs = numberOfBombs + app.bombs(row,col+1) + app.bombs(row+1,col) + app.bombs(row+1,col+1)-app.bombs(row,col);
%           end
%           
%           if (row==1) & (col==3)
%               numberOfBombs = numberOfBombs  + app.bombs(row,col-1) +  app.bombs(row+1,col) + app.bombs(row+1,col-1)-app.bombs(row,col);
%           end
%           
%           if(row==4)& (col ==1)
%              numberOfBombs = numberOfBombs  + app.bombs(row,col+1) + app.bombs(row-1,col) + app.bombs(row-1,col+1)-app.bombs(row,col); 
%                  
%           end
%           
%           if(row==4)&(col==3)
%             numberOfBombs = numberOfBombs  + app.bombs(row,col-1) + app.bombs(row-1,col) + app.bombs(row-1,col-1)-app.bombs(row,col); 
% 
%           end
          %---------
          %SO FAR we have the bomb number and its location 
           % This was an alternate way to dispaly adjacent bombs -- figured out
           % after lots of testing :(
          for r = (row - 1):(row + 1)
            for c = (col - 1):(col + 1)
                if (r >= 1) & (r <= 4) & (c >= 1) & (c <= 3)
                    numberOfBombs = numberOfBombs + app.bombs(r, c);
                end
            end
          end
          numberOfBombs = numberOfBombs -app.bombs(row,col); 
       
          ef = [
              
          app.EditField,app.EditField2,app.EditField3;
          app.EditField4,app.EditField5,app.EditField6;
          app.EditField7,app.EditField8,app.EditField9,
          app.EditField10,app.EditField11,app.EditField12
          
          ]
          
          ef(row,col).Value = num2str(numberOfBombs);
          % How to match the matrix value to the GUI board 
          
          %Rename editfields
          
        %Instead of 
          % app.compnent.BackgroundColor =' r' 
            
        end

        % Button pushed function: PlaceFlagButton
        function PlaceFlagButtonPushed(app, event)
            %Place Flag Button
           
            % If theres a bomb at the   bomb position then make the 
            % lamp indicator 'BOMB FLAGGED' light up  
            % IF theres a bomb then subtract 1 from the BOMBSREMAINING AND
            % FLAGS REMAINING 
            % 
            % IF they place a flag thats not a bomb still minus 1 and light
            % indicator


            % IF you lose THEN every button except quit game will be
            % disabled 
                  ef = [
              
                          app.EditField,app.EditField2,app.EditField3;
                          app.EditField4,app.EditField5,app.EditField6;
                          app.EditField7,app.EditField8,app.EditField9,
                          app.EditField10,app.EditField11,app.EditField12
          
                       ]
            
            
            startFlag =  app.FlagsRemainingEditField.Value;
            startBomb = app.BombsRemainingEditField.Value;
            [row,col]= find(app.board==1);% Gives us location of cursor
            
            if app.memory(row,col) == 0 % Prevents from selecting the flag again 
                app.memory(row,col) = 1 ;
            

            if ( app.bombs(row,col)==1 )
               %app.BombFlaggedLamp.Color =  'g';
               app.BombFlaggedLamp.Enable ='on';
               app.FlagsRemainingEditField.Value =  startFlag-1;
               app.BombsRemainingEditField.Value = startBomb-1;
               %turn the edit field at bombs[row,col] into red color
               %background
               ef(row,col).BackgroundColor = [1,0,0]; 
               %ex. app.EditField11.BackgroundColor = [1 0 0];
            else
                %app.NotaBombLamp.Color = 'r';
                app.NotaBombLamp.Enable = 'on';
                app.FlagsRemainingEditField.Value = startFlag-1;
            end

            if(app.FlagsRemainingEditField.Value==0 && app.BombsRemainingEditField.Value>0)
                 app.GameStatusEditField.Value = 'You lose. Try again!';
                 %HELP:figure out how to stop/end the game
                 %Disable buttons
                 %Giant if statement using boolean to turn on and off in
                 %start
                 
                 for i=1:row % Lights up LEDS up to cursor
                    for j=1:col
                        
                            
                            app.board(i,j)= 1;
                            UpdateBoard(app.a,app.pins,app.board)
                            pause(1);
                        
                    end
                end
            end
            if(app.BombsRemainingEditField.Value==0)
                app.GameStatusEditField.Value = 'Congrats! You won the game';
                %figure out how to stop/end the game
                %Victory screen creates a V
               VictoryScreen =[1 0 1 ;
                 1 0 1;
                 1 0 1;
                 0 1 0   ];
                UpdateBoard(app.a,app.pins,VictoryScreen);
            end
            
         
            end 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.GameStatusEditFieldLabel = uilabel(app.UIFigure);
            app.GameStatusEditFieldLabel.Text = 'Game Status';
            app.GameStatusEditField = uieditfield(app.UIFigure, 'text');
            app.NumberOfBombsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberOfBombsSpinnerLabel.Text = 'Number Of Bombs';
            app.NumberOfBombsSpinner = uispinner(app.UIFigure);
            app.NumberOfBombsSpinner.Limits = [1 3];
            app.NumberOfBombsSpinner.Value = 1;
            app.NumberofFlagsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofFlagsSpinnerLabel.Text = 'Number of Flags';
            app.NumberofFlagsSpinner = uispinner(app.UIFigure);
            app.NumberofFlagsSpinner.Limits = [1 9];
            app.NumberofFlagsSpinner.Value = 1;
            app.FlagsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.FlagsRemainingEditFieldLabel.Text = 'Flags Remaining';
            app.FlagsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.BombsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.BombsRemainingEditFieldLabel.Text = 'Bombs Remaining';
            app.BombsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.PlaceFlagButton = uibutton(app.UIFigure, 'push');
            app.PlaceFlagButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceFlagButtonPushed, true);
            app.PlaceFlagButton.Text = 'Place Flag';
            app.MakeSelectionButton = uibutton(app.UIFigure, 'push');
            app.MakeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MakeSelectionButtonPushed, true);
            app.MakeSelectionButton.Text = 'Make Selection';
            app.BombFlaggedLampLabel = uilabel(app.UIFigure);
            app.BombFlaggedLampLabel.Text = 'Bomb Flagged';
            app.BombFlaggedLamp = uilamp(app.UIFigure);
            app.NotaBombLampLabel = uilabel(app.UIFigure);
            app.NotaBombLampLabel.Text = 'Not a Bomb';
            app.NotaBombLamp = uilamp(app.UIFigure);
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitGameButtonPushed, true);
            app.QuitGameButton.Text = 'Quit Game ';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField4 = uieditfield(app.UIFigure, 'text');
            app.EditField5 = uieditfield(app.UIFigure, 'text');
            app.EditField6 = uieditfield(app.UIFigure, 'text');
            app.EditField7 = uieditfield(app.UIFigure, 'text');
            app.EditField8 = uieditfield(app.UIFigure, 'text');
            app.EditField9 = uieditfield(app.UIFigure, 'text');
            app.EditField10 = uieditfield(app.UIFigure, 'text');
            app.EditField11 = uieditfield(app.UIFigure, 'text');
            app.EditField12 = uieditfield(app.UIFigure, 'text');
            app.MINESWEEPERLabel = uilabel(app.UIFigure);
            app.MINESWEEPERLabel.Text = 'MINESWEEPER';
            app.BoardLabel = uilabel(app.UIFigure);
            app.BoardLabel.Text = 'Board';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minesweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end